classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpurGearDesignerUIFigure        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DesignGuide1Tab                 matlab.ui.container.Tab
        SpurGearDesignerLabel           matlab.ui.control.Label
        PinionLabel                     matlab.ui.control.Label
        Numofteeth1                     matlab.ui.control.NumericEditField
        Numberofteethanywherebetween12to300EditFieldLabel  matlab.ui.control.Label
        MaterialDropDownLabel           matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        rpm1label                       matlab.ui.control.Label
        Revloutionperminute             matlab.ui.control.NumericEditField
        GearLabel                       matlab.ui.control.Label
        Numberofteethanywherebetween12to300EditField_2Label  matlab.ui.control.Label
        Numofteeth2                     matlab.ui.control.NumericEditField
        MaterialDropDown_2Label         matlab.ui.control.Label
        MaterialDropDown_2              matlab.ui.control.DropDown
        ToothFormDropDownLabel          matlab.ui.control.Label
        ToothFormDropDown               matlab.ui.control.DropDown
        PowerRequiredLabel              matlab.ui.control.Label
        PowerRequired                   matlab.ui.control.NumericEditField
        SelectMeshingTypeButtonGroup    matlab.ui.container.ButtonGroup
        Image                           matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        ExternalMesh                    matlab.ui.control.RadioButton
        InternalMesh                    matlab.ui.control.RadioButton
        IncludeDynamicAnalysisCheckBox  matlab.ui.control.CheckBox
        ExportCheckBox                  matlab.ui.control.CheckBox
        CalculateButton                 matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        DesignGuide1NumberofTeethLabel  matlab.ui.control.Label
        DesignGuide2Tab                 matlab.ui.container.Tab
        DesignGuide3Tab                 matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            numofteeth1 = app.Numofteeth1.Value;
            numofteeth2 = app.Numofteeth2.Value;
            profile = app.ToothFormDropDown.Value;
            material1 = app.MaterialDropDown.Value;
            material2 = app.MaterialDropDown_2.Value;
            power = app.PowerRequired.Value;
            rpm = app.Revloutionperminute.Value;
            externalmesh = app.ExternalMesh.Value;
            internalmesh = app.InternalMesh.Value;

            if power <= 0
                message = 'Power Required must be larger than zero.';
                title = 'Error';
                h = errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
                return
            end
                        
            if strcmp(material1, 'Cast Iron') == 1 && strcmp(material2, 'Steel') == 1
                message = 'Incorrect material combination. Recommended: Steel for driving gear, Cast Iron for driven gear';
                title = 'Error';
                h = errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
                return
            end
            
            if numofteeth1 == numofteeth2
                message = 'Number of teeth cannot be the same for both pinion and gear';
                title = 'Error';
                h =     errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
                return
            end
            
            
            strength1 = S_o(material1);
            strength2 = S_o(material2);
                  
            if strcmp(material1, material2) == 1
                
                if numofteeth1 > numofteeth2
                    y = LewisFactor(numofteeth2, profile);
                    N = numofteeth2;
                    strength = strength2;
                elseif numofteeth2 > numofteeth1
                    y = LewisFactor(numofteeth1, profile);
                    N = numofteeth1;
                    strength = strength1;
                end
                
            else                
                  y1 = LewisFactor(numofteeth1, profile);
                  y2 = LewisFactor(numofteeth2, profile);
         
                  if strength1*y1 > strength2*y2
                      y = y2;
                      N = numofteeth2;
                      strength = strength2;
                  elseif strength2*y2 > strength1*y1
                      y = y1;
                      N = numofteeth1;
                      strength = strength1;
                  end
            end
            
            M_t = (power*60*10^3)/(2*pi*rpm);
            
            ind_s = (strength*10^6)/2;
            
            k_assum = 4;
            
            module = (((2*M_t)/(k_assum*y*pi^2*N*ind_s))^(1/3))*10^3;
            
            assum_module = ModuleSelector(module);
            
            [dia, true_module, allow_s, ind_s_true, speed] = module_corrector(assum_module, M_t, k_assum, y, N, rpm, strength);
                                                
            k_true = k_assum*ind_s_true/allow_s;
            
            face_width = k_true*pi*true_module;
                                    
            VR = numofteeth1/numofteeth2;
                           
            if app.IncludeDynamicAnalysisCheckBox.Value == 1
                
                if strcmp(material1, 'Steel') == 1 && strcmp(material2, 'Steel') ~= 1
                    title = 'Data Required';
                    prompt = '\fontsize{10} Enter Brinell Hardness Number For Driving Gear (any value between 150 and 400)';
                    opts.Interpreter = 'tex'; 
                    numlines = [1, length(title) + 50];
                    defaultans = {'150'};
                    result = inputdlg(prompt, title, numlines, defaultans, opts);
                    BHN1 = str2double(result{1});
                    BHN2 = BHN1;
                elseif strcmp(material1, 'Steel') ~= 1 && strcmp(material2, 'Steel') == 1
                    title = 'Data Required';
                    prompt = '\fontsize{10} Enter Brinell Hardness Number For Driven Gear (any value between 150 and 400)';
                    opts.Interpreter = 'tex'; 
                    numlines = [1, length(title) + 50];
                    defaultans = {'150'};
                    result = inputdlg(prompt, title, numlines, defaultans, opts);
                    BHN2 = str2double(result{1});
                    BHN1 = BHN2;
                elseif strcmp(material1, 'Steel') == 1 && strcmp(material2, 'Steel') == 1
                    title = 'Data Required';
                    prompt = {'\fontsize{10} Enter Brinell Hardness Number For Driving Gear (any value between 150 and 400)',...
                              '\fontsize{10} Enter Brinell Hardness Number For Driven Gear (any value between 150 and 400)'};
                    opts.Interpreter = 'tex'; 
                    numlines = [1, length(title) + 50];
                    defaultans = {'150', '150'};
                    results = inputdlg(prompt, title, numlines, defaultans, opts);
                    BHN1 = str2double(results{1});
                    BHN2 = str2double(results{2});
                else
                    BHN1 = NaN;
                    BHN2 = NaN;
                end
                
                if (BHN1 > 400 || BHN2 > 400) || (BHN1 < 150 || BHN2 < 150)
                    message = 'Brinell Hardness Number must be between 150 and 400';
                    title = 'Error';
                    error = errordlg(message, title);
                    msgboxFontSize(error, 10, 'ignorebuttons', true);
                    return
                end
                
                
                K = StressFatigueFactor(material1, material2, BHN1, BHN2, profile);
                
                if numofteeth1 < numofteeth2
                    if externalmesh == 1
                        Q = (2*numofteeth2)/(numofteeth1 + numofteeth2);
                    elseif internalmesh == 1
                        Q = (2*numofteeth2)/(numofteeth1 - numofteeth2);
                    end
                elseif numofteeth2 < numofteeth1
                    if externalmesh == 1
                        Q = (2*numofteeth1)/(numofteeth1 + numofteeth2);
                    elseif internalmesh == 1
                        Q = (2*numofteeth1)/(numofteeth1 - numofteeth2);
                    end 
                end                
                
                [true_module, dia, face_width, F_end, F_dynamic, F_wear] = dynamiccheck(face_width, true_module, dia, K, Q, strength, y, M_t, rpm, material1, material2, profile, N, speed);
                                
            end
                                    
            dia1 = dia;
            
            dia2 = dia1/VR;
            
            if strcmp('Involute 14.5', profile) == 1 || strcmp('Full Depth 20', profile) == 1
                addendum = true_module;
                dedendum = 1.25*true_module;
                working_depth = 2*true_module;
                whole_depth = 2.25*true_module;
                tooth_thickness = 1.5708*true_module;
                minimum_clearance = 0.25*true_module;
                fillet_radius = 0.4*true_module;
            elseif strcmp('Stub 20', profile) == 1
                addendum = 0.8*true_module;
                dedendum = true_module;
                working_depth = 1.6*true_module;
                whole_depth = 1.8*true_module;
                tooth_thickness = 1.5708*true_module;
                minimum_clearance = 0.2*true_module;
                fillet_radius = 0.4*true_module;
            end
            
            names = {'Material'; 'Diameter (mm)';...
                'Module (mm)'; 'Addendum (mm)';...
                'Dedendum (mm)'; 'Working Depth (mm)';...
                'Whole Depth (mm)'; 'Tooth Thickness (mm)';...
                'Face Width (mm)'; 'Miniumum Clearance (mm)';...
                'Fillet Radius (mm)'};
            pinion = {material1; dia1; true_module; addendum; dedendum; working_depth; whole_depth; tooth_thickness; face_width; minimum_clearance; fillet_radius};
            gear = {material2; dia2; true_module; addendum; dedendum; working_depth; whole_depth; tooth_thickness; face_width; minimum_clearance; fillet_radius};

            if app.IncludeDynamicAnalysisCheckBox.Value == 1
                names = [names; 'Endurance Load (N)'; 'Dynamic Load (N)'; 'Wear Load (N)'];
                pinion = [pinion; F_end; F_dynamic; F_wear];
                gear = [gear; F_end; F_dynamic; F_wear];
            end
                                    
            T = table(names, pinion, gear, 'VariableNames', {'Attributes', 'Pinion', 'Gear'});
                
            if app.ExportCheckBox.Value == 1            
                [file, path] = uiputfile('Results.xlsx');
                filename = fullfile(path, file);
                writetable(T, filename, 'Sheet', 1);
                prompt = 'Calculation Successful!';
                title = 'Success';
                h = msgbox(prompt, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
            else
                f = uifigure;
                uitable(f, 'Data', T);
                prompt = 'Calculation Successful!';
                title = 'Success';
                h = msgbox(prompt, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
            end         
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           app.Numofteeth1.Value = 12;
           app.Numofteeth2.Value = 12;
           app.ToothFormDropDown.Value = 'Involute 14.5';
           app.MaterialDropDown.Value = 'Steel';
           app.MaterialDropDown_2.Value = 'Steel';
           app.PowerRequired.Value = 1;
           app.Revloutionperminute.Value = 1;
           app.ExternalMesh.Value = 1;
           app.InternalMesh.Value = 0;
           app.IncludeDynamicAnalysisCheckBox.Value = 0;
           app.ExportCheckBox.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpurGearDesignerUIFigure = uifigure('Visible', 'off');
            app.SpurGearDesignerUIFigure.Name = 'Spur Gear Designer';
            app.TabGroup = uitabgroup(app.SpurGearDesignerUIFigure);
            app.DesignGuide1Tab = uitab(app.TabGroup);
            app.DesignGuide1Tab.Title = 'Design Guide 1';
            app.SpurGearDesignerLabel = uilabel(app.DesignGuide1Tab);
            app.SpurGearDesignerLabel.Text = 'Spur Gear Designer';
            app.PinionLabel = uilabel(app.DesignGuide1Tab);
            app.PinionLabel.Text = 'Pinion';
            app.Numofteeth1 = uieditfield(app.DesignGuide1Tab, 'numeric');
            app.Numofteeth1.Limits = [12 300];
            app.Numofteeth1.RoundFractionalValues = 'on';
            app.Numofteeth1.Value = 12;
            app.Numberofteethanywherebetween12to300EditFieldLabel = uilabel(app.DesignGuide1Tab);
            app.Numberofteethanywherebetween12to300EditFieldLabel.Text = 'Number of teeth (anywhere between 12 to 300)';
            app.MaterialDropDownLabel = uilabel(app.DesignGuide1Tab);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.DesignGuide1Tab);
            app.MaterialDropDown.Items = {'Steel', 'Cast Iron'};
            app.MaterialDropDown.Value = 'Steel';
            app.rpm1label = uilabel(app.DesignGuide1Tab);
            app.rpm1label.Text = 'Revolution Per Minute';
            app.Revloutionperminute = uieditfield(app.DesignGuide1Tab, 'numeric');
            app.Revloutionperminute.Limits = [1 Inf];
            app.Revloutionperminute.Value = 1;
            app.GearLabel = uilabel(app.DesignGuide1Tab);
            app.GearLabel.Text = 'Gear';
            app.Numberofteethanywherebetween12to300EditField_2Label = uilabel(app.DesignGuide1Tab);
            app.Numberofteethanywherebetween12to300EditField_2Label.Text = 'Number of teeth (anywhere between 12 to 300)';
            app.Numofteeth2 = uieditfield(app.DesignGuide1Tab, 'numeric');
            app.Numofteeth2.Limits = [12 300];
            app.Numofteeth2.RoundFractionalValues = 'on';
            app.Numofteeth2.Value = 12;
            app.MaterialDropDown_2Label = uilabel(app.DesignGuide1Tab);
            app.MaterialDropDown_2Label.Text = 'Material';
            app.MaterialDropDown_2 = uidropdown(app.DesignGuide1Tab);
            app.MaterialDropDown_2.Items = {'Steel', 'Cast Iron'};
            app.MaterialDropDown_2.Value = 'Steel';
            app.ToothFormDropDownLabel = uilabel(app.DesignGuide1Tab);
            app.ToothFormDropDownLabel.Text = 'Tooth Form';
            app.ToothFormDropDown = uidropdown(app.DesignGuide1Tab);
            app.ToothFormDropDown.Items = {'Involute 14.5', 'Full Depth 20', 'Stub 20'};
            app.ToothFormDropDown.Value = 'Involute 14.5';
            app.PowerRequiredLabel = uilabel(app.DesignGuide1Tab);
            app.PowerRequiredLabel.Text = 'Power Required (in kW)';
            app.PowerRequired = uieditfield(app.DesignGuide1Tab, 'numeric');
            app.PowerRequired.Limits = [0 Inf];
            app.PowerRequired.Value = 1;
            app.SelectMeshingTypeButtonGroup = uibuttongroup(app.DesignGuide1Tab);
            app.SelectMeshingTypeButtonGroup.Title = 'Select Meshing Type';
            app.Image = uiimage(app.SelectMeshingTypeButtonGroup);
            app.Image.ImageSource = 'fig1.jpg';
            app.Image_2 = uiimage(app.SelectMeshingTypeButtonGroup);
            app.Image_2.ImageSource = 'fig2.jpg';
            app.ExternalMesh = uiradiobutton(app.SelectMeshingTypeButtonGroup);
            app.ExternalMesh.Text = '';
            app.ExternalMesh.Value = true;
            app.InternalMesh = uiradiobutton(app.SelectMeshingTypeButtonGroup);
            app.InternalMesh.Text = '';
            app.IncludeDynamicAnalysisCheckBox = uicheckbox(app.DesignGuide1Tab);
            app.IncludeDynamicAnalysisCheckBox.Text = 'Include Dynamic Analysis';
            app.ExportCheckBox = uicheckbox(app.DesignGuide1Tab);
            app.ExportCheckBox.Text = 'Export the results into an Excel file';
            app.CalculateButton = uibutton(app.DesignGuide1Tab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResetButton = uibutton(app.DesignGuide1Tab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DesignGuide1NumberofTeethLabel = uilabel(app.DesignGuide1Tab);
            app.DesignGuide1NumberofTeethLabel.Text = 'Design Guide 1 (Number of Teeth)';
            app.DesignGuide2Tab = uitab(app.TabGroup);
            app.DesignGuide2Tab.Title = 'Design Guide 2';
            app.DesignGuide3Tab = uitab(app.TabGroup);
            app.DesignGuide3Tab.Title = 'Design Guide 3';
            % Show the figure after all components are created
            app.SpurGearDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpurGearDesignerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpurGearDesignerUIFigure)
        end
    end
end